classdef BeachProfiles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ControlsPanel         matlab.ui.container.Panel
        StopButton            matlab.ui.control.Button
        RunButton             matlab.ui.control.Button
        InitializeButton      matlab.ui.control.Button
        HelpButton            matlab.ui.control.Button
        SetConditionsPanel    matlab.ui.container.Panel
        graindiametermmLabel  matlab.ui.control.Label
        diameterSpinner       matlab.ui.control.Spinner
        sea_level_up          matlab.ui.control.Button
        sea_level_down        matlab.ui.control.Button
        sea_level_axes        matlab.ui.control.UIAxes
        BeachProfilesLabel    matlab.ui.control.Label
        profileAxes           matlab.ui.control.UIAxes
    end


    properties (Access = private)
        x           % cross-shore positions (m)
        x_rel       % relative cross-shore positions
        z           % elevation (m)
        d           % grain diameter
        A           % z = Ax^(2/3)
        sea_level   % m above starting sea level
        shoreline   % position of shoreline
        
        max_sl = 10;
        sl_step = 0.25;
        
        % constants
        kappa = 0.8;
        g = 9.81;
        rho = 1027;
        A_table;
        h_c = 15;   % (m)
        h_dune = 5; % (m)
        R
        
        stopped     % app running or stopped
        wait_time = 0.1;
    end

    methods (Access = private)
    
        function updatePlot(app)
            % find new shoreline
            app.shoreline = find(app.z > app.sea_level, 1, 'last');
            if isempty(app.shoreline)
                app.shoreline = 0;
            elseif app.shoreline == length(app.z)
                app.shoreline = app.shoreline -1;
            end
            
            % update beach profile plot
            ax = app.profileAxes;
            cla(ax);
            plot(ax, app.x, app.z, 'k', 'linewidth', 2);
            plot(ax, [app.x(app.shoreline+1) app.x(end)], [app.sea_level app.sea_level], 'b', 'linewidth', 2);
            patch(ax, [app.x(app.shoreline+1) app.x(end) fliplr(app.x(app.shoreline+1:end))],...
                [app.sea_level app.sea_level fliplr(app.z(app.shoreline+1:end))], ...
                [0 0.5 1], 'facealpha', 0.4);
        end
        
        function updateSeaLevel(app, slr)
            app.sea_level = app.sea_level + slr;
            % update sl indicator
            bar(app.sea_level_axes, 0, app.sea_level, 3);
            
            % move shoreline
            app.x_rel = app.x_rel + (sign(slr)*app.R);
            app.z = -app.A*(sign(app.x_rel).*abs(app.x_rel).^(2/3));
            app.z(app.z > app.h_dune) = app.h_dune;
            app.updatePlot();
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % intialize
            app.A_table = [[0.063 0.0672 0.0714 0.0756 0.0798 0.084 0.0872 0.0904 0.0936 0.0968];...
                            [0.100 0.103 0.106 0.109 0.112 0.115 0.117 0.119 0.121 0.123];...
                            [0.125 0.127 0.129 0.131 0.133 0.135 0.137 0.139 0.141 0.143];...
                            [0.145 0.1466 0.1482 0.1498 0.1514 0.153 0.1546 0.1562 0.1578 0.1594];...
                            [0.161 0.1622 0.1634 0.1646 0.1658 0.167 0.1682 0.1694 0.1706 0.1718];...
                            [0.173 0.1742 0.1754 0.1766 0.1778 0.179 0.1802 0.1814 0.1826 0.1838];...
                            [0.185 0.1859 0.1868 0.1877 0.1886 0.1895 0.1904 0.1913 0.1922 0.1931];...
                            [0.194 0.1948 0.1956 0.1964 0.1972 0.198 0.1988 0.1996 0.2004 0.2012];...
                            [0.202 0.2028 0.2036 0.2044 0.2052 0.206 0.2068 0.2076 0.2084 0.2092];...
                            [0.210 0.2108 0.2116 0.2124 0.2132 0.2140 0.2148 0.2156 0.2164 0.2172]];
            app.InitializeButtonPushed();
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.RunButton.Enable = 'off';
            app.InitializeButton.Enable = 'off';
            app.StopButton.Enable = 'on';
            app.sea_level_up.Enable = 'off';
            app.sea_level_down.Enable = 'off';
            app.stopped = 0;
            app.diameterSpinner.Editable = 'off';
            
            dir = 1;
            while ~app.stopped
                app.updateSeaLevel(dir*app.sl_step);
                app.updatePlot();
                if abs(app.sea_level) >= app.max_sl || app.sea_level <= app.z(end) || app.sea_level >= app.z(1)
                    dir = -1*dir;
                end
                pause(app.wait_time);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.RunButton.Enable = 'on';
            app.InitializeButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.sea_level_up.Enable = 'on';
            app.sea_level_down.Enable = 'on';
            app.stopped = 1;
            app.diameterSpinner.Editable = 'on';
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            % set run state
            app.RunButton.Enable = 'on';
            app.InitializeButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.sea_level_up.Enable = 'on';
            app.sea_level_down.Enable = 'on';
            app.stopped = 1;
            app.diameterSpinner.Editable = 'on';
            
            % initalize user inputs
            app.d = app.diameterSpinner.Value;
            app.sea_level = 0;
            
            % find a
            row = floor(app.d/0.1);
            col = floor(mod(app.d, 0.1)*100) + 1;
            app.A = app.A_table(row, col);
            
            % find distance to depth of closure and 3 m height
            hc_dist = (app.h_c/app.A)^(3/2);
            dune_dist = (app.h_dune/app.A)^(3/2);
            app.x = linspace(floor(-dune_dist), ceil(hc_dist), 150);        
            app.z = -app.A*(sign(app.x).*abs(app.x).^(2/3));
            app.x_rel = app.x;
                        
            % R = SL/(h+B)
            app.R = (app.sl_step + dune_dist + hc_dist)/(app.h_c + app.h_dune);
            
            % set up sea level axes
            ax = app.sea_level_axes;
            xticks(ax, []);
            xticklabels(ax, []);
            title(ax, 'Sea level (m)');
            ylim(ax, [-app.max_sl, app.max_sl]);
            
            % set up profile axes
            ax = app.profileAxes;
            xlabel(ax, 'cross-shore dist. (m)', 'fontsize', 16)
            xlim(ax, [app.x(1) app.x(end)]);
            ylabel(ax, 'elevation (m)', 'fontsize', 16);
            ylim(ax, [app.z(end), app.z(1)]);
            box(ax, 'on');
            grid(ax, 'on');
            hold(ax, 'on');
            set(ax, 'fontsize', 16);
            app.updatePlot();
        end

        % Button pushed function: sea_level_up
        function sea_level_upButtonPushed(app, event)
            app.updateSeaLevel(app.sl_step);
            if app.sea_level >= min(app.max_sl, app.z(1))
                app.sea_level_up.Enable = 'off';
            end
            app.updatePlot();
        end

        % Button pushed function: sea_level_down
        function sea_level_downButtonPushed(app, event)
            app.updateSeaLevel(-app.sl_step);
            if app.sea_level <= max(-app.max_sl, app.z(end))
                app.sea_level_down.Enable = 'off';
            end
            app.updatePlot();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.StopButton = uibutton(app.ControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RunButton = uibutton(app.ControlsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.InitializeButton = uibutton(app.ControlsPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.HelpButton = uibutton(app.ControlsPanel, 'push');
            app.HelpButton.Icon = 'help_icon.png';
            app.HelpButton.Text = 'Help';
            app.SetConditionsPanel = uipanel(app.UIFigure);
            app.SetConditionsPanel.Title = 'Set Conditions';
            app.graindiametermmLabel = uilabel(app.SetConditionsPanel);
            app.graindiametermmLabel.Text = 'grain diameter (mm)';
            app.diameterSpinner = uispinner(app.SetConditionsPanel);
            app.diameterSpinner.Step = 0.01;
            app.diameterSpinner.Limits = [0 1.09];
            app.diameterSpinner.Value = 0.5;
            app.sea_level_up = uibutton(app.SetConditionsPanel, 'push');
            app.sea_level_up.ButtonPushedFcn = createCallbackFcn(app, @sea_level_upButtonPushed, true);
            app.sea_level_up.Text = '+';
            app.sea_level_down = uibutton(app.SetConditionsPanel, 'push');
            app.sea_level_down.ButtonPushedFcn = createCallbackFcn(app, @sea_level_downButtonPushed, true);
            app.sea_level_down.Text = '-';
            app.sea_level_axes = uiaxes(app.SetConditionsPanel);
            title(app.sea_level_axes, 'Sea Level')
            app.BeachProfilesLabel = uilabel(app.UIFigure);
            app.BeachProfilesLabel.Text = 'Beach Profiles';
            app.profileAxes = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = BeachProfiles

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end