classdef CEM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        coastAxes                    matlab.ui.control.UIAxes
        SetConditionsPanel           matlab.ui.container.Panel
        waveangledegEditFieldLabel   matlab.ui.control.Label
        waveangleEditField           matlab.ui.control.NumericEditField
        shorelineshapeDropDownLabel  matlab.ui.control.Label
        shorelineshapeDropDown       matlab.ui.control.DropDown
        waveheightmEditFieldLabel    matlab.ui.control.Label
        waveheightEditField          matlab.ui.control.NumericEditField
        CoastlineEvolutionLabel      matlab.ui.control.Label
        ControlsPanel                matlab.ui.container.Panel
        InitializeButton             matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        SpeedKnobLabel               matlab.ui.control.Label
        SpeedKnob                    matlab.ui.control.Knob
        fluxAxes                     matlab.ui.control.UIAxes
        waveAxes                     matlab.ui.control.UIAxes
        HelpButton                   matlab.ui.control.Button
    end


    properties (Access = private)
        x                     % crosshore shoreline positions
        y                     % longshore shoreline positions
        y_lims = 100;
        alpha                 % wave angle relative to horizontal
        wave_height           % local wave height
        
        rho = 1020            % kg/m^3
        g = 9.8               % m^2/s
        
        wait_time             % set by speed slider
        stop                  % variable used to stop run
    end

    methods (Access = private)
    
        function [l1, l2, p] = plot_alpha(app, i, shore_angle, dir, show_marker)
                % plot wave and shoreline angle
                ax = app.coastAxes;
                l1 = plot(ax, [i-3, i+3], [-(3*sind(shore_angle)) + app.y(i),...
                    (3*sind(shore_angle)) + app.y(i)],...
                    'r', 'linewidth', 2);
                if dir > 0
                    l2 = plot(ax, [i-3, i+3], [-(3*sind(shore_angle)) + app.y(i),...
                        (6*sind(app.alpha)) - (3*sind(shore_angle)) + app.y(i)],...
                    'r--', 'linewidth', 2);
                else
                    l2 = plot(ax, [i-3, i+3], [-(6*sind(app.alpha)) + (3*sind(shore_angle)) + app.y(i),...
                        (3*sind(shore_angle)) + app.y(i)],...
                    'r--', 'linewidth', 2);
                end
                if show_marker
                    p = plot(ax, i, app.y(i), 'r*', 'markersize', 10);   
                end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.stop = 1;
            app.RunButton.Enable = 'off';
            app.StopButton.Enable = 'off';
                        
            % clear wave axes
            ax = app.waveAxes;
            xticklabels(ax, []);
            xticks(ax, []);
            yticklabels(ax, []);
            yticks(ax, []);
            axis(ax, 'equal')
            title(ax, 'wave angle');
            
            % set shoreline axes
            ax = app.coastAxes;
            xticklabels(ax, []);
            ylabel(ax, 'cross-shore dist. (m)');
            
            % set flux axes
            ax = app.fluxAxes;
            set(ax, 'defaultAxesColorOrder', [[0 0 0]; [0 0 0]]);
            xlim(ax, [0 150]);
            yyaxis(ax, 'left');
            ylabel(ax, 'sed. flux');
            yticklabels(ax, []);
            yyaxis(ax, 'right');
            ylabel(ax, 'vol. change');
            yticklabels(ax, [0]);
            xlabel(ax, 'longshore dist. (m)');
            
            app.Initialize();
            
            
        end

        % Button pushed function: InitializeButton
        function Initialize(app, event)
            % initialize user input fields
            app.alpha = app.waveangleEditField.Value;
            app.wave_height = app.waveheightEditField.Value;
            app.wait_time = .01* (10/app.SpeedKnob.Value);            
            
            yyaxis(app.fluxAxes, 'left');
            cla(app.fluxAxes);
            yyaxis(app.fluxAxes, 'right');
            cla(app.fluxAxes);
            
            % initilize coastline graph
            switch app.shorelineshapeDropDown.Value
                case 'linear'
                    app.y = (ones(1, 151)).*10;
                    
                case 'headland'
                    app.y = -10.*cos(linspace(0, 2*pi, 151)) + 10;
                    
                case 'bay'
                    app.y = 10.*cos(linspace(0, 2*pi, 151)) + 10;
                    
            end
            
            app.x = 0:1:150;            
            
            % build bathy grid
            h = nan(app.y_lims+1, length(app.x));
            for r = 1:app.y_lims+1
                for col = 1:length(app.x)
                    if r <= app.y(col)
                        h(r, col) = nan; % land
                    else
                        h(r, col) = -0.015 * (r - app.y(col)); % depth (m)
                    end
                end
            end

            
            % plot bathy
            ax = app.coastAxes;
            cla(ax);        
            colormap(ax, 'winter');
            set(ax, 'color', 'yellow');
            contourf(ax, app.x, (0:1:app.y_lims), h, 20);
            hold(ax, 'on');
            plot(ax, app.x, app.y, 'k-', 'linewidth', 2); ax.XLim = [0 length(app.x)-1]; ax.YLim = [0 app.y_lims];
            
            
            % add wave angle plot
            ax = app.waveAxes;
            cla(ax)
            hold(ax, 'on');
            for i = (0:2)
                x = [-cosd(app.alpha) 0 cosd(app.alpha)];
                y = [-sind(app.alpha) 0 sind(app.alpha)];
                if abs(app.alpha) > 45
                    x = x + i;
                else
                    y = y + i;
                end
                plot(ax, x, y, 'k--');
            end
            hold(ax, 'off')
            
            
            xticklabels(ax, []);
            xticks(ax, []);
            yticklabels(ax, []);
            yticks(ax, []);
            axis(ax, 'equal')
            
            % allow run
            app.RunButton.Enable = 'on';
            
            % initialize current run
            app.stop = 0;
        end

        % Button pushed function: RunButton
        function Run(app, event)
            % disable initialize button while running
            app.InitializeButton.Enable = 'off';
            % disable run button while running
            app.RunButton.Enable = 'off';
            % enable stop button
            app.StopButton.Enable = 'on';
            app.stop = 0;
            
            % set up axes
            hold(app.coastAxes, 'on');
            hold(app.fluxAxes, 'on');
            title(app.fluxAxes, 'flux out');
            
            vol_out = zeros(1, 151);
            vol_in = zeros(1, 151);        
            shore_angle = vol_in;
            dir = vol_in;
            
            % start loop
            for i = (1:151)
                if app.stop
                    break
                end
                % get local shoreline angle
                if i == 1
                    shore_angle(i) = atan2d(app.y(i+1) - app.y(i), 1);
                elseif i == 151
                    shore_angle(i) = atan2d(app.y(i) - app.y(i-1), 1);
                else
                    shore_angle(i) = atan2d(app.y(i+1) - app.y(i-1), 2);
                end
                
                % convert angle to CEM angles
                cem_angle = shore_angle(i);
                if cem_angle > 90
                    cem_angle = 90 - angle(i);
                end
                alpha = app.alpha - cem_angle;
                
                % get transport dir
                dir(i) = 1;
                if alpha < 0
                    dir(i) = -1;
                end
                
                [l1, l2, p] = app.plot_alpha(i, shore_angle(i), dir(i), 1);                
                                
                % plot flux out
                ax = app.fluxAxes;
                yyaxis(ax, 'left');
                vol_out(i) = abs(1.1 * app.rho * app.g^(3/2) * app.wave_height^(5/2) * cosd(alpha) * sind(alpha));
                plot(ax, i, vol_out(i), 'ro', 'markersize', 4, 'markerfacecolor', 'r'); 
                
                % animate
                pause(app.wait_time)
                delete(l1); delete(l2); delete(p);
            end
            
            
            % get volume in
            for i = (151:-1:1)
                if app.stop
                    break
                end
                j = i + dir(i);  
                
                % plot angle and direction
                [l1, l2] = app.plot_alpha(i, shore_angle(i), dir(i), 0);
                if dir(i) > 0
                    arrow = plot(app.coastAxes, app.x(i), app.y(i), 'k>', 'markersize', 8, ...
                        'markerfacecolor', 'yellow');
                else                    
                    arrow = plot(app.coastAxes, app.x(i), app.y(i), 'k<', 'markersize', 8, ...
                        'markerfacecolor', 'yellow');
                end
                
                if j > 0 && j < 152
                    vol_in(j) = vol_in(j) + vol_out(i);            
                    % plot flux in
                    ax = app.fluxAxes;
                    yyaxis(ax, 'left');
                    title(ax, 'flux in')
                    plot(ax, j, vol_in(j), 'bo', 'markersize', 4, 'markerfacecolor', 'b'); 
                end 
                    
                pause(app.wait_time);
                delete(l1); delete(l2); delete(arrow);
            end
            % get volume change
            vol_change = vol_in - vol_out;
            
            % plot volume change
            title(ax, '')  ;
            yyaxis(ax, 'right');
            plot(ax, app.x(2:150), 10.*vol_change(2:150), 'k--', 'linewidth', 2);
            pause(app.wait_time)
            
            % plot shoreline advance/retreat
            ax = app.coastAxes;
            % create own color map
            colors = redblue(100);
            vol_range = 2*max(abs(min(vol_change(2:150))), abs(max(vol_change(2:150))));
            % shift to all positive volumes
            vol_change = vol_change + vol_range/2;
            for i = (2:length(app.x)-1)
                vol = vol_change(i);
                color_index = 1+floor(99*(vol/vol_range));
                plot(ax, [app.x(i), app.x(i)], [app.y(i)+1, app.y(i)+3],...
                    '-','color', colors(color_index, :),'linewidth', 4);
            end          
            
            % end procedure
            app.InitializeButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.stop = 1;
            hold(app.coastAxes, 'off');
            hold(app.fluxAxes, 'off');
        end

        % Button pushed function: StopButton
        function Stop(app, event)
            % re-enable initialize and start button
            app.InitializeButton.Enable = 'on';
            % disable stop button 
            app.StopButton.Enable = 'off';            
            % stop run
            app.stop = 1;
        end

        % Value changed function: SpeedKnob
        function SpeedKnobValueChanged(app, event)
            app.wait_time = .01* (10/app.SpeedKnob.Value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.coastAxes = uiaxes(app.UIFigure);
            app.SetConditionsPanel = uipanel(app.UIFigure);
            app.SetConditionsPanel.Title = 'Set Conditions';
            app.waveangledegEditFieldLabel = uilabel(app.SetConditionsPanel);
            app.waveangledegEditFieldLabel.Text = 'wave angle (deg)';
            app.waveangleEditField = uieditfield(app.SetConditionsPanel, 'numeric');
            app.waveangleEditField.Limits = [-90 90];
            app.waveangleEditField.RoundFractionalValues = 'on';
            app.waveangleEditField.Value = 45;
            app.shorelineshapeDropDownLabel = uilabel(app.SetConditionsPanel);
            app.shorelineshapeDropDownLabel.Text = 'shoreline shape';
            app.shorelineshapeDropDown = uidropdown(app.SetConditionsPanel);
            app.shorelineshapeDropDown.Items = {'linear', 'headland', 'bay'};
            app.shorelineshapeDropDown.Value = 'headland';
            app.waveheightmEditFieldLabel = uilabel(app.SetConditionsPanel);
            app.waveheightmEditFieldLabel.Text = 'wave height (m)';
            app.waveheightEditField = uieditfield(app.SetConditionsPanel, 'numeric');
            app.waveheightEditField.Limits = [0 10];
            app.waveheightEditField.Value = 2;
            app.CoastlineEvolutionLabel = uilabel(app.UIFigure);
            app.CoastlineEvolutionLabel.Text = 'Coastline Evolution';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.InitializeButton = uibutton(app.ControlsPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @Initialize, true);
            app.InitializeButton.Text = 'Initialize';
            app.RunButton = uibutton(app.ControlsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @Run, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.ControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @Stop, true);
            app.StopButton.Text = 'Stop';
            app.SpeedKnobLabel = uilabel(app.ControlsPanel);
            app.SpeedKnobLabel.Text = 'Speed';
            app.SpeedKnob = uiknob(app.ControlsPanel, 'continuous');
            app.SpeedKnob.Limits = [1 10];
            app.SpeedKnob.ValueChangedFcn = createCallbackFcn(app, @SpeedKnobValueChanged, true);
            app.SpeedKnob.MinorTicks = [];
            app.SpeedKnob.Value = 4;
            app.fluxAxes = uiaxes(app.UIFigure);
            app.waveAxes = uiaxes(app.UIFigure);
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.Icon = 'help_icon.png';
            app.HelpButton.Text = 'Help';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CEM

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end