classdef Refraction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ControlsPanel                matlab.ui.container.Panel
        InitializeButton             matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
        SpeedSliderLabel             matlab.ui.control.Label
        SpeedSlider                  matlab.ui.control.Slider
        SetConditionsPanel           matlab.ui.container.Panel
        waveangledegEditFieldLabel   matlab.ui.control.Label
        waveangleEditField           matlab.ui.control.NumericEditField
        shorelineshapeDropDownLabel  matlab.ui.control.Label
        shorelineshapeDropDown       matlab.ui.control.DropDown
        shoalsCheckBox               matlab.ui.control.CheckBox
        canyonCheckBox               matlab.ui.control.CheckBox
        wavelengthmLabel             matlab.ui.control.Label
        wavelengthEditField          matlab.ui.control.NumericEditField
        waveperiodsecLabel           matlab.ui.control.Label
        waveperiodEditField          matlab.ui.control.NumericEditField
        refracAxes                   matlab.ui.control.UIAxes
        waveAxes                     matlab.ui.control.UIAxes
        WaveRefractionLabel          matlab.ui.control.Label
        coastAxes                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        wave_angle_deep                  % degrees
        L_deep                           % deep water wavelength (m)
        T                                % wave period
        slope = 0.01;                    % shoreface slope
        
        x_pos                            % longshore wave positions
        y_pos                            % cross-shore wave positions
        
        x_step = 5;                      % longshore cell size (m)
        x = (0:5:800);                   % longshore domain
        y_step = 5;                      % cross-shore cell size (m)
        y = (0:5:1000);                  % cross-shore domain
        h = zeros(201, 161)              % bathy grid
        shoreline                        % y pos of shoreline
        shore_angles
        
        stopped                          % signifies app is stopped
        pause_time
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.stopped = 1;
            app.RunButton.Enable = 'off';
            app.StopButton.Enable = 'off';
            
            % set up wave axes
            ax = app.waveAxes;
            xticklabels(ax, []);
            xticks(ax, []);
            yticklabels(ax, []);
            yticks(ax, []);
            axis(ax, 'equal');
            title(ax, 'deep wave angle');
            
            % set up shoreline aces
            ax = app.coastAxes;
            ylim(ax, [0 (length(app.y)-1) * app.y_step]);
            xlim(ax, [0, (length(app.x)-1) * app.x_step]);
            ylabel(ax, 'cross-shore pos. (m)');
            
            % set refraction aces
            ax = app.refracAxes;
            ylim(ax, [0 (length(app.y)-1) * app.y_step]);
            xlim(ax, [0, 180]);
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            % init props
            app.pause_time = (11-app.SpeedSlider.Value) * 0.05;
            app.x_pos = nan(1, 15); 
            app.y_pos = nan(1, 15); 
            
            % initialize user input
            app.wave_angle_deep = app.waveangleEditField.Value;
            app.L_deep = app.wavelengthEditField.Value;
            app.T = app.waveperiodEditField.Value;
            
            % initialize coastline
            switch app.shorelineshapeDropDown.Value
                case 'linear'
                    app.shore_angles = zeros(1, length(app.x));
                    app.shoreline = (ones(1, length(app.x))).*10;
                    
                case 'headland'
                    app.shore_angles = linspace(0, 2*pi, length(app.x));
                    app.shoreline = -10.*cos(app.shore_angles) + 11;
                    
                case 'bay'
                    app.shore_angles = linspace(0, 2*pi, length(app.x));
                    app.shoreline = 10.*cos(app.shore_angles) + 11;                    
            end            
            
            % build bathy grid
            for r = 1:length(app.y)
                for c = 1:length(app.x)
                    if r <= app.shoreline(c)
                        app.h(r, c) = 0;
                    else
                        app.h(r, c) = -(app.y_step * app.slope) * (r - app.shoreline(c));
                    end
                end
            end
            
            % add shoals
            if app.shoalsCheckBox.Value == 1
                radius = 25;    % radius of shoals (m)
                height = 5;    % max height of shoals (m)
                shoal_slope = height/radius;
                center_r = 2*floor(length(app.y)/3);
                center_c = floor(length(app.x)/4);
                for r = (center_r - ceil(radius/app.y_step):1:center_r + ceil(radius/app.y_step))
                    for c = (center_c - ceil(radius/app.x_step):1:center_c + ceil(radius/app.x_step))
                        dist = sqrt((center_r-r)^2 + (center_c-c)^2);
                        elev = shoal_slope*(radius-dist);
                        app.h(r, c) = app.h(r, c) + elev;
                    end
                end
            end
            
            % add canyon
            if app.canyonCheckBox.Value == 1
                radius = 25;  % radius of canyon (m)
                depth = 5;   % max depth of canyon (m)
                canyon_slope = depth/radius;
                center_r = 2*floor(length(app.y)/3);
                center_c = ceil(3*(length(app.x)/4));
                for r = (center_r - ceil(radius/app.y_step):1:center_r + ceil(radius/app.y_step))
                    for c = (center_c - ceil(radius/app.x_step):1:center_c + ceil(radius/app.x_step))
                        dist = sqrt((center_r-r)^2 + (center_c-c)^2);
                        elev = canyon_slope*(radius-dist);
                        app.h(r, c) = app.h(r, c) - elev;
                    end
                end
            end
            app.h(app.h == 0) = nan;
            
            % plot bathy
            ax = app.coastAxes;
            cla(ax);
            contourf(ax, app.x, app.y, app.h);
            colorbar(ax);
            hold(ax, 'on');
            
            
            % initialize wave vector
            crest_length = length(app.x)*app.x_step;
            crest_pos = linspace(0, crest_length, length(app.x_pos));
            xdists = crest_pos.*cosd(app.wave_angle_deep);
            ydists = crest_pos.*sind(app.wave_angle_deep);
            if app.wave_angle_deep < 0
                app.y_pos = app.y(end)+ydists;
                app.x_pos = app.x(end)-(xdists(end)-xdists);
            else
                app.y_pos = app.y(end)-(ydists(end)-ydists);
                app.x_pos = xdists;
            end
            
            % plot wave crest
            plot(ax, app.x_pos, app.y_pos, 'd-r', 'markerfacecolor', 'r', 'markersize', 6, 'linewidth', 2);
            
            % add wave angle plot
            ax = app.waveAxes;
            cla(ax)
            hold(ax, 'on');
            for i = (0:2)
                wave_x = [-cosd(app.wave_angle_deep) 0 cosd(app.wave_angle_deep)];
                wave_y = [-sind(app.wave_angle_deep) 0 sind(app.wave_angle_deep)];
                if abs(app.wave_angle_deep) > 45
                    wave_x = wave_x + i;
                else
                    wave_y = wave_y + i;
                end
                plot(ax, wave_x, wave_y, 'k--');
            end
            hold(ax, 'off');
            
            xticklabels(ax, []);
            xticks(ax, []);
            yticklabels(ax, []);
            yticks(ax, []);
            axis(ax, 'equal')
            
            ax = app.refracAxes;
            plot(ax, [0 0], [0 (length(app.y)-1) * app.y_step], 'k--', 'linewidth', 2);
            
            
            % initialize run settings
            app.RunButton.Enable = 'on';
            app.stopped = 0;
        end

        % Callback function
        function RunButtonPushed(app, event)
            % disable initialize button while running
            app.InitializeButton.Enable = 'off';
            % disable run button while running
            app.RunButton.Enable = 'off';
            % enable stop button
            app.StopButton.Enable = 'on';
            app.stopped = 0;
            
            pi = 3.14159;
            g = 9.81;
                        
            c = -1*ones(size(app.x_pos)); % vector of wave ray celerities
            wave_angles = nan(size(c));
            
            step = 1;
            while length(c) > 1
                % pause function
                if app.stopped
                    return
                end
                
                for i = (1:1:length(c))
                    % get angles along wave_crest
                    left = i-1;
                    right = i+1;
                    if left < 1
                        left = 1;
                    end
                    if right > length(c)
                        right = right-1;
                    end
                    wave_angles(i) = atan2d(app.y_pos(right) - app.y_pos(left), app.x_pos(right) - app.x_pos(left));
                    % get wave celerities
                    depth = -app.h(find(app.y <= app.y_pos(i), 1, 'last'), find(app.x <= app.x_pos(i), 1, 'last'));
                    if isempty(depth)
                        c(i) = nan;
                        continue
                    end
                    wave_length = app.L_deep * (tanh((((2.0*pi/app.T)^2)*(depth/g))^.75))^(2/3);
                    c(i) = wave_length/app.T;
                    %c(i) = sqrt(g*h);
                end
                
                % remove nans
                app.x_pos(isnan(c)) = [];
                app.y_pos(isnan(c)) = [];
                wave_angles(isnan(c)) = [];
                c(isnan(c)) = [];
                
                % update wave ray positions
                x_new = app.x_pos + (app.T*c).*sind(wave_angles);
                y_new = app.y_pos - abs((app.T*c).*cosd(wave_angles));
                
                % merge rays which cross over
                cross_indices = find(x_new(1:end-1) > x_new(2:end));
                wave_angles(cross_indices+1) = (wave_angles(cross_indices+1) + wave_angles(cross_indices))/2;
                app.x_pos(cross_indices) = [];
                app.y_pos(cross_indices) = [];
                wave_angles(cross_indices) = [];
                c(cross_indices) = [];
                
                x_new = app.x_pos + (app.T*c).*sind(wave_angles);
                y_new = app.y_pos - abs((app.T*c).*cosd(wave_angles));
                
                ax = app.coastAxes;
                % plot wave rays
                for i = (1:1:length(app.x_pos))
                    plot(ax, [app.x_pos(i) x_new(i)], [app.y_pos(i) y_new(i)], 'o-r', 'markerfacecolor', 'r',...
                        'markersize', 2);
                end
                % plot new wave crest
                app.x_pos = x_new;
                app.y_pos = y_new;
                if mod(step, 6) == 0
                    plot(ax, app.x_pos, app.y_pos, 'o-r', 'markerfacecolor', 'r', 'markersize', 4);
                end
                step = step +1;
                pause(app.pause_time);
                
                % plot refrac
%                 avg_dist = mean(app.y_pos);
%                 total_alpha = 0;
%                 for i = (1:1:length(app.x_pos))
%                     x_index = find(app.x <= app.x_pos(i), 1, 'last');
%                     alph = abs(wave_angles(i) - rad2deg(app.shore_angles(x_index)));
%                     if alph > 180
%                         alph = alph - 360;
%                     end
%                     total_alpha = total_alpha + abs(alph);
%                 end
%                 avg_alpha = total_alpha/length(wave_angles);
%                 
%                 ax = app.refracAxes;
%                 plot(ax, avg_alpha, avg_dist, '*b', 'markersize', 6);
%                 hold(ax, 'on')
                
            end            
            
            % end procedure
            app.InitializeButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.stopped = 1;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % re-enable initialize and start button
            app.InitializeButton.Enable = 'on';
            app.RunButton.Enable = 'on';
            % disable stop button 
            app.StopButton.Enable = 'off';            
            % stop run
            app.stopped = 1;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.InitializeButton = uibutton(app.ControlsPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.StopButton = uibutton(app.ControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RunButton = uibutton(app.ControlsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SpeedSliderLabel = uilabel(app.ControlsPanel);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.ControlsPanel);
            app.SpeedSlider.Limits = [1 10];
            app.SpeedSlider.MajorTicks = [1 10];
            app.SpeedSlider.MajorTickLabels = {'1', '10'};
            app.SpeedSlider.Value = 2;
            app.SetConditionsPanel = uipanel(app.UIFigure);
            app.SetConditionsPanel.Title = 'Set Conditions';
            app.waveangledegEditFieldLabel = uilabel(app.SetConditionsPanel);
            app.waveangledegEditFieldLabel.Text = 'wave angle (deg)';
            app.waveangleEditField = uieditfield(app.SetConditionsPanel, 'numeric');
            app.waveangleEditField.Limits = [-90 90];
            app.waveangleEditField.RoundFractionalValues = 'on';
            app.waveangleEditField.Value = 30;
            app.shorelineshapeDropDownLabel = uilabel(app.SetConditionsPanel);
            app.shorelineshapeDropDownLabel.Text = 'shoreline shape';
            app.shorelineshapeDropDown = uidropdown(app.SetConditionsPanel);
            app.shorelineshapeDropDown.Items = {'linear', 'headland', 'bay'};
            app.shorelineshapeDropDown.Value = 'headland';
            app.shoalsCheckBox = uicheckbox(app.SetConditionsPanel);
            app.shoalsCheckBox.Text = 'shoals';
            app.canyonCheckBox = uicheckbox(app.SetConditionsPanel);
            app.canyonCheckBox.Text = 'canyon';
            app.wavelengthmLabel = uilabel(app.SetConditionsPanel);
            app.wavelengthmLabel.Text = 'wavelength (m)';
            app.wavelengthEditField = uieditfield(app.SetConditionsPanel, 'numeric');
            app.wavelengthEditField.Limits = [5 50];
            app.wavelengthEditField.RoundFractionalValues = 'on';
            app.wavelengthEditField.Value = 20;
            app.waveperiodsecLabel = uilabel(app.SetConditionsPanel);
            app.waveperiodsecLabel.Text = 'wave period (sec)';
            app.waveperiodEditField = uieditfield(app.SetConditionsPanel, 'numeric');
            app.waveperiodEditField.Limits = [5 20];
            app.waveperiodEditField.RoundFractionalValues = 'on';
            app.waveperiodEditField.Value = 10;
            app.refracAxes = uiaxes(app.UIFigure);
            app.waveAxes = uiaxes(app.UIFigure);
            app.WaveRefractionLabel = uilabel(app.UIFigure);
            app.WaveRefractionLabel.Text = 'Wave Refraction';
            app.coastAxes = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Refraction

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end