classdef SeaCliffs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ControlsPanel                  matlab.ui.container.Panel
        StopButton                     matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        InitializeButton               matlab.ui.control.Button
        HelpButton                     matlab.ui.control.Button
        SetConditionsPanel             matlab.ui.container.Panel
        WaveheightmSpinnerLabel        matlab.ui.control.Label
        waveHeight                     matlab.ui.control.Spinner
        SealevelmSpinnerLabel          matlab.ui.control.Label
        seaLevel                       matlab.ui.control.Spinner
        PrecipitationinyrSpinnerLabel  matlab.ui.control.Label
        precipLevel                    matlab.ui.control.Spinner
        SeaCliffErosionLabel           matlab.ui.control.Label
        profileAxes                    matlab.ui.control.UIAxes
        waterAxes                      matlab.ui.control.UIAxes
        retreatAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        x           % cross-shore array (m)
        dx          % cross-shore resolution (m)
        min_x
        max_x
        x_cliff     % x positions of cliff face (m)
        
        z           % elevation array (m)
        dz          % elevation resolution (m)
        max_z
        min_z
        z_cliff     % z positions of cliff face (m)
        
        wl          % water level (m)
        wl_intersect% index at which the water line intersects land
        slope       % beach slope        
        precip      % precipiation (in/yr)
        Hs          % wave height
        T           % wave period (s)
        sea_level   % (m)
        sed_vol     % (m^3)
        comp_strength
        tens_strength
        shear_strength
        
        t           % model run time (yrs)
        dt          % timestep (yrs)        
        cliff_base  % index of cliff base
        cliff_top   % index of cliff top
        
        % tracking variables
        max_vals    % maximum stored values
        Hs_hist
        sl_hist
        precip_hist
        pos_hist
        retreat_rate
        notch_hist        
        
        stopped
    end     
    
    methods (Access = private)
        
        function updateProfilePlot(app)
            drawnow
            [plotx, plotz] = app.mergeArrays();
            
            % get wave break height and position                            
            app.getPlottableWaterLevel(plotx, plotz);
            
            % plot cliff and water
            ax = app.profileAxes;
            cla(ax)
            plot(ax, plotx, plotz, 'k-', 'linewidth', 2);
            plot(ax, plotx(1:app.wl_intersect+1), [app.wl(1:app.wl_intersect), plotz(app.wl_intersect+1)], 'b-', 'linewidth', 2);
            % fill water
            patch(ax, [plotx(1:app.wl_intersect), fliplr(plotx(1:app.wl_intersect+1))],...
                [app.wl(1:app.wl_intersect), fliplr(plotz(1:app.wl_intersect+1))], ...
                [0 0.5 1], 'facealpha', 0.3);
            % fill land
            patch(ax, [plotx app.x(end) app.x(1) app.x(1)],...
                [plotz app.min_z app.min_z app.z(1)], ...
                [0.851 0.3294 0.102], 'facealpha', 0.3);
            
            text(ax, app.x(floor(length(app.x)/6)), app.max_z - 4*app.dz, ['t=', num2str(app.t), ' (yrs)'],...
                'fontsize', 14);
            drawnow
        end
                
        function getPlottableWaterLevel(app, mergeX, mergeZ)     
            % get wave breaking depth and height
            Hb = 0.39*(9.81^(1/5))*(app.T*app.Hs^2)^(2/5);
            Hb_depth = Hb/0.8;
            
            % runup
            shoreline = app.sea_level/app.slope;               
            break_point = -Hb_depth/app.slope;
            slope_runup = (Hb_depth)/(abs(shoreline - break_point));
            vertical_runup = 0.18*(9.81^.5)*(slope_runup)*(Hb^.5)*(app.T);
            horizontal_runup = vertical_runup/slope_runup;
            Hshore = 0.2*Hb; % broken wave height at the shoreline (Camfield and Street, 1969)
                            
            A = zeros(size(mergeX));

            for i = (1:1:length(mergeZ))
                if mergeZ(i) < app.sea_level
                    A(i) = max(Hshore, 0.8 * (app.sea_level - mergeZ(i)));
                % runup
                else                              
                    % within swash zone
                    if horizontal_runup >= (mergeX(i) - shoreline)
                        A(i) = Hshore - ((Hshore/horizontal_runup) * (mergeX(i) - shoreline));
                    else
                    % outside swash
                    A(i) = 0;
                    end
                end
            end
            
            app.wl = (A/2) .* sin((2*pi)/(app.T*sqrt(9.81*Hb_depth)).*(mergeX) + (-app.t*pi/2)) + app.sea_level;
            % runup
            indices = find(mergeZ > app.sea_level & A > mergeZ);
            app.wl(indices) = app.wl(indices) + (mergeZ(indices) - app.sea_level);
            % find where water intersects land
            app.wl_intersect = find(mergeZ > app.wl, 1)-1;
            app.wl(app.wl_intersect+1:end) = nan;
            
            % find upper-bound water
            indices = find(~isnan(app.wl(1:end-1)) & mergeX(1:end-1) > mergeX(2:end));
            app.wl(indices) = mergeZ(indices);
        end
        
        function updateSecondaryPlots(app)
            drawnow
            t_array = (0:app.dt:(app.max_vals*app.dt)-app.dt);
            if app.t >= app.max_vals*app.dt                
                t_array = (app.t-(app.max_vals*app.dt)+app.dt:app.dt:app.t);
                % trim_arrays
                overflow = length(app.Hs_hist) - app.max_vals;
                app.Hs_hist = app.Hs_hist(overflow+1:end);
                app.sl_hist = app.sl_hist(overflow+1:end);
                app.precip_hist = app.precip_hist(overflow+1:end);
                app.pos_hist = app.pos_hist(overflow+1:end);
                app.retreat_rate = app.retreat_rate(overflow+1:end);
                app.notch_hist = app.notch_hist(overflow+1:end);
            end
            
            % update wave and sea level plot
            ax = app.waterAxes;
            yyaxis(ax, 'left');
            cla(ax);
            plot(ax, [t_array(1) t_array(end)], [0 0], '--');
            plot(ax, t_array, app.Hs_hist, 'linewidth', 2);
            plot(ax, t_array, app.sl_hist, 'linewidth', 2);
            ylim(ax, [-3, 4]);
            
            yyaxis(ax, 'right');
            cla(ax);
            plot(ax, t_array, app.precip_hist, 'linewidth', 2);
            ylim(ax, [0, 100]);
            
            xlim(ax, [t_array(1), t_array(end)]);
            start_tick = t_array(1) + (10 - mod(t_array(1), 10));
            xticks(ax, (start_tick:100:t_array(end)));
            
            % update retreat plots
            ax = app.retreatAxes;
            yyaxis(ax, 'left');
            cla(ax);
            plot(ax, t_array, app.pos_hist, 'linewidth', 2);
            plot(ax, t_array, app.notch_hist, 'linewidth', 2);
            ylim(ax, [0, app.max_x]);
            
            yyaxis(ax, 'right');
            cla(ax);
            plot(ax, t_array, app.retreat_rate, 'linewidth', 2);
            ylim(ax, [0, 0.1]);
            
            xlim(ax, [t_array(1), t_array(end)]);
            xticks(ax, (start_tick:10:t_array(end)));
            drawnow
        end
        
        function H_cliff = getCliffFaceWaveHeight(app, H_deep)
            [mergeX, mergeZ] = app.mergeArrays();
            % get wave breaking depth and height
            Hb = 0.39*(9.81^(1/5))*(app.T*H_deep^2)^(2/5);
            Hb_depth = Hb/0.8;
            
            cliff_base_depth = app.sea_level - app.z(app.cliff_base);
            
            % SCENARIO 1: wave breaks against cliff face
            if Hb_depth < cliff_base_depth
                H_cliff = Hb;
                return
            end            
            
            shoreline = app.sea_level/app.slope;
            subaqueous_slope = (mergeZ(app.cliff_base) - mergeZ(1)) / (mergeX(app.cliff_base) - mergeX(1)); % slope of platform + beach            
            setup = 0.18*(9.81^.5)*(subaqueous_slope)*(H_deep^.5)*(app.T);
            beach_width = mergeX(app.cliff_base) - shoreline;
            if beach_width > 0
                setup = setup/beach_width;
            end
            water_level = app.sea_level + setup;
            instantaneous_shoreline = water_level/app.slope;
            break_point = -(Hb_depth)/app.slope + shoreline;
            
            % SCENARIO 2: cliff toe submerged - broken wave reaches cliff toe
            if instantaneous_shoreline > mergeX(app.cliff_base)
                dx_shoreline_theoretical = Hb_depth/subaqueous_slope;
                H_cliff = Hb - ((0.8 * Hb / dx_shoreline_theoretical) * (mergeX(app.cliff_base) - break_point));
                return
            end
            
            slope_runup = (Hb_depth + water_level)/(abs(instantaneous_shoreline - break_point));
            vertical_runup = 0.18*(9.81^.5)*(slope_runup)*(Hb^.5)*(app.T);
            total_slope = (mergeZ(app.cliff_base) + Hb_depth)/(mergeX(app.cliff_base) - break_point);
            horizontal_runup = vertical_runup/total_slope;
            
            % SCENARIO 3: runup reaches cliff toe
            if horizontal_runup > (mergeX(app.cliff_base) - instantaneous_shoreline)
                Hshore = 0.2*Hb; % broken wave height at the shoreline (Camfield and Street, 1969)
                H_cliff = Hshore - ((Hshore/horizontal_runup) * (mergeX(app.cliff_base) - instantaneous_shoreline));
                return
            end
            % SCENARIO 4: runup does not reach cliff toe
            H_cliff = 0;
        end
        
        function A = getA(app)
            A = 200;
        end
        
        function B = getB(app)
            B_dry = 1.0;
            B_sat = 0.7;
            max_precip = app.precipLevel.Limits(2);
            B = B_dry - ((app.precip/max_precip)*(B_dry-B_sat));
        end
        
        function makeNotch(app, H)
            % get A
            A = app.getA(); % constant
            % get B
            B = app.getB(); % based on precipitation
            
            % constants
            rho  = 1025;
            g = 9.81;
            kappa = 0.4;
            z_eff = H;
            
            gamma = log(A/B);
            
            % get notch erosion
            notch = zeros(size(app.x_cliff));
            H_crit = (app.comp_strength/(rho*g))*exp(-gamma);
            if H > H_crit
                max_notch = app.dt * kappa * (gamma + log(rho * g * H / app.comp_strength));
            else
                max_notch = 0;
            end

            % get vertical erosion
            for i = (1:1:length(app.x_cliff))
                notch(i) = max((exp(-(abs(app.z_cliff(i)-app.sea_level)/z_eff)^2)) * max_notch);
            end
            notch(isnan(notch)) = 0;
            
            app.x_cliff = app.x_cliff + notch;
            
            if app.x_cliff(end) > app.x(app.cliff_top)
                app.cliff_top = app.cliff_top + 1;
            end
        end
        
        function [mergeX, mergeZ] = mergeArrays(app)
            % merge horizontal and vertical components of x and z
            mergeX = [app.x(1:app.cliff_base), app.x_cliff, app.x(app.cliff_top:end)];
            mergeZ = [app.z(1:app.cliff_base), app.z_cliff, app.z(app.cliff_top:end)];
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % init run
            app.T = 15;
            app.slope = 0.1;
            app.stopped = 1;
            app.InitializeButtonPushed();
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            % set buttons
            app.RunButton.Enable = 'on';
            app.InitializeButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.stopped = 1;
            
            % initialize user input
            app.precip = 50;
            app.sed_vol = 50;
            app.comp_strength = 10.0 * 10^4;
            app.tens_strength = 60 * 10^3;
            app.shear_strength = 90 * 10^3;      
            app.waveHeightValueChanged();
            app.seaLevelValueChanged();
            app.precipLevelValueChanged();
            
            % initialize tracking
            app.max_vals = 500;
            app.Hs_hist = nan(1, app.max_vals);
            app.sl_hist = nan(1, app.max_vals);
            app.precip_hist = nan(1, app.max_vals);
            app.pos_hist = nan(1, app.max_vals);
            app.retreat_rate = nan(1, app.max_vals);
            app.notch_hist = nan(1, app.max_vals);
            
            % set ranges
            app.t = 0;
            app.dt = 0.2;
            app.dx = .5;
            app.dz = .1;
            app.min_x = -30;
            app.max_x = 50;
            app.min_z = -5;
            app.max_z = 5;
            num_cliff_face_points = app.max_z/app.dz+1;
            
            % initialize position vectors with cliff at x = 0;
            app.x = (app.min_x:app.dx:app.max_x);
            app.x_cliff = zeros(1, num_cliff_face_points);
            app.z = app.slope * app.x;
            app.z(app.x > 0) = app.max_z;
            app.z_cliff = (0:app.dz:app.max_z);
            app.cliff_base = find(app.x >= 0, 1);
            app.cliff_top = app.cliff_base+1;
                        
            % intialize profile plot
            ax = app.profileAxes;
            xlabel(ax, 'cross-shore dist. (m)', 'fontsize', 16)
            xlim(ax, [app.x(1) app.x(end)]);
            ylabel(ax, 'elevation (m)', 'fontsize', 16);
            ylim(ax, [app.min_z, app.max_z+2]);
            box(ax, 'on');
            grid(ax, 'on');
            hold(ax, 'on');
            set(ax, 'fontsize', 16);
            
            app.updateProfilePlot();
            
            % initialize wave and sea level plot
            ax = app.waterAxes;
            box(ax, 'on');
            grid(ax, 'on');
            hold(ax, 'on');
            xticklabels(ax, []);
            yyaxis(ax, 'left');
            ylabel(ax, 'meters');
            yyaxis(ax, 'right');
            ylabel(ax, 'in/yr');            
            
            % initialize retreat plots
            ax = app.retreatAxes;
            box(ax, 'on');
            grid(ax, 'on');
            hold(ax, 'on');
            set(ax, 'fontsize', 16);
            xlabel(ax, 'time (yr)');
            yyaxis(ax, 'left');
            ylabel(ax, 'meters');
            yyaxis(ax, 'right');
            ylabel(ax, 'm/yr');
            
            
            app.updateSecondaryPlots();
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % set buttons
            app.RunButton.Enable = 'off';
            app.InitializeButton.Enable = 'off';
            app.StopButton.Enable = 'on';
            app.stopped = 0;
            
            % constants
            gamma_cliff = 18. * 10^3;            
            
            while ~ app.stopped
                % get wave height normally distributed aroundHs
                H = 1.0 * randn() + app.Hs;
                if H < 0
                    H = 0;
                end
                
                % get waver level/wave energy at cliff face
                H = app.getCliffFaceWaveHeight(H);
                % determine extent of notch development
                app.makeNotch(H);
                                
                % debris: TODO
                
                % failure critera
                failed = 0;
                [max_notch_x, max_notch_ind] = max(app.x_cliff);
                cliff_face_x = app.x_cliff(end);
                N = max_notch_x - cliff_face_x;
                
                % stop criteria
                if max_notch_x >= app.x(end)
                    break;
                end

                % determine stress
                total_vol = 0;
                for i = (max_notch_ind:length(app.x_cliff))
                    if app.x_cliff(i) == cliff_face_x
                        break;
                    end
                    total_vol = total_vol + 0.5*(app.max_z - app.z_cliff(i)) * ...
                        (app.x(i) - app.x(i+1)); % calculate piecewise volumes of material to be used to find weighted average of overhang height
                end
                H_overhang = total_vol / (N); % average cantilever overhang height
                sigma_max = 3 * N^2 * gamma_cliff / H_overhang; % maximum tensile strength (Young and Ashford, 2008)
                tau_max = (3/2) * N * gamma_cliff; % maximum shear strength (Young and Ashford, 2008)
                
                if abs(sigma_max) >= app.tens_strength  % tensile failure criteria
                    failed = 1;
                elseif tau_max >= app.shear_strength % shear failure criteria
                    failed = 1;
                end                
                
                % cliff failure
                if failed == 1
                    app.x_cliff(max_notch_ind:end) = max_notch_x;
                    app.cliff_base = find((app.x < app.x_cliff(1)), 1, 'last');
                    app.cliff_top = find(app.x >= app.x_cliff(end), 1);
                    app.z(app.z(1:app.cliff_top-1) == app.max_z) = 0;
                end
                                
                app.updateProfilePlot();
                
                i = sum(~isnan(app.Hs_hist))+1;
                app.Hs_hist(i) = app.Hs;
                app.sl_hist(i) = app.sea_level;
                app.precip_hist(i) = app.precip;
                app.pos_hist(i) = cliff_face_x;
                app.retreat_rate(i) = (app.pos_hist(i) - app.pos_hist(1))/i;
                app.notch_hist(i) = N;
                
                app.updateSecondaryPlots();
                                 
                app.t = app.t + app.dt;                
            end
            
            app.StopButtonPushed();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % set buttons
            app.RunButton.Enable = 'on';
            app.InitializeButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.stopped = 1;
        end

        % Value changed function: waveHeight
        function waveHeightValueChanged(app, event)
            app.Hs = app.waveHeight.Value;
            
        end

        % Value changed function: seaLevel
        function seaLevelValueChanged(app, event)
            app.sea_level = app.seaLevel.Value;
            
        end

        % Value changed function: precipLevel
        function precipLevelValueChanged(app, event)
            app.precip = app.precipLevel.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.StopButton = uibutton(app.ControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RunButton = uibutton(app.ControlsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.InitializeButton = uibutton(app.ControlsPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.HelpButton = uibutton(app.ControlsPanel, 'push');
            app.HelpButton.Text = 'Help';
            app.SetConditionsPanel = uipanel(app.UIFigure);
            app.SetConditionsPanel.Title = 'Set Conditions';
            app.WaveheightmSpinnerLabel = uilabel(app.SetConditionsPanel);
            app.WaveheightmSpinnerLabel.Text = 'Wave height (m)';
            app.waveHeight = uispinner(app.SetConditionsPanel);
            app.waveHeight.Step = 0.5;
            app.waveHeight.Limits = [0.5 6];
            app.waveHeight.ValueChangedFcn = createCallbackFcn(app, @waveHeightValueChanged, true);
            app.waveHeight.Value = 2;
            app.SealevelmSpinnerLabel = uilabel(app.SetConditionsPanel);
            app.SealevelmSpinnerLabel.Text = 'Sea level (m)';
            app.seaLevel = uispinner(app.SetConditionsPanel);
            app.seaLevel.Step = 0.5;
            app.seaLevel.Limits = [-3 3];
            app.seaLevel.ValueChangedFcn = createCallbackFcn(app, @seaLevelValueChanged, true);
            app.PrecipitationinyrSpinnerLabel = uilabel(app.SetConditionsPanel);
            app.PrecipitationinyrSpinnerLabel.Text = 'Precipitation (in/yr)';
            app.precipLevel = uispinner(app.SetConditionsPanel);
            app.precipLevel.Limits = [0 100];
            app.precipLevel.ValueChangedFcn = createCallbackFcn(app, @precipLevelValueChanged, true);
            app.precipLevel.Value = 50;
            app.SeaCliffErosionLabel = uilabel(app.UIFigure);
            app.SeaCliffErosionLabel.Text = 'Sea Cliff Erosion';
            app.profileAxes = uiaxes(app.UIFigure);
            app.waterAxes = uiaxes(app.UIFigure);
            app.retreatAxes = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = SeaCliffs

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end