classdef SedTrans < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ControlsPanel                matlab.ui.container.Panel
        StopButton                   matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
        SetConditionsPanel           matlab.ui.container.Panel
        shorelineshapeDropDownLabel  matlab.ui.control.Label
        shorelineshapeDropDown       matlab.ui.control.DropDown
        breakingwaveheightmLabel     matlab.ui.control.Label
        waveHeightSpinner            matlab.ui.control.Spinner
        WaveInducedSedimentTransportLabel  matlab.ui.control.Label
        coastAxes                    matlab.ui.control.UIAxes
        HelpButton                   matlab.ui.control.Button
        breakingwaveangledegLabel    matlab.ui.control.Label
        waveAngleGauge               matlab.ui.control.SemicircularGauge
        wave_down                    matlab.ui.control.Button
        wave_up                      matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        posSlider                    matlab.ui.control.Slider
        fluxAxes                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        breaking_wave_angle              % degrees
        breaking_wave_height             % m
        slope = 0.015                    % shoreface slope
                
        x                                % longshore domain
        y                                % cross-shore domain
        shoreline                        % y pos of shoreline
        angles                           % local shoreline angles
        h                                % bathy grid
        wave_handle
        
        pos                              % current shoreline position
        stopped                          % signifies app is stopped
    end

    methods (Access = private)
    
        function render_plot(app)
        end
        
        function render_wave(app)
            % initialize wave vector
            crest_pos = linspace(0, length(app.x), 30); 
            xdists = crest_pos.*cosd(app.breaking_wave_angle);
            ydists = crest_pos.*sind(app.breaking_wave_angle);
            if sind(app.breaking_wave_angle) < 0 % anchor to left side
                y_pos = app.y(end)+ydists;
                x_pos = app.x(end)-(xdists(end)-xdists);
            else % anchor to right side
                y_pos = app.y(end)-(ydists(end)-ydists);
                x_pos = xdists;
            end
            
%             % move wave crest to fit
%             min_y = min(y_pos);
%             if min_y < 6.5
%                 app.y_pos = app.y_pos + (6.5 - min_y);
%                 out_of_bounds = find (app.y_pos > app.y(end));
%                 app.removeWaveRaysHelper(out_of_bounds);
%             end
            
            % plot wave crest
            delete(app.wave_handle);
            app.wave_handle = plot(app.coastAxes, x_pos, y_pos, '-k', 'linewidth', 2);  
        end
        
        function render_bathy(app)                        
            % initialize coastline
            rads = linspace(0, 2*pi, length(app.x));
            switch app.shorelineshapeDropDown.Value
                case 'linear'
                    app.angles = zeros(1, length(app.x));
                    app.shoreline = (ones(1, length(app.x))).*30;
                    
                case 'headland'
                    app.angles = atand(sin(rads));
                    app.shoreline = -20.*cos(rads) + 20;
                    
                case 'bay'
                    app.angles = atand(-sin(rads));
                    app.shoreline = 20.*cos(rads) + 20;                    
            end
            
            % build bathy grid
            for r = 1:length(app.y)
                for col = 1:length(app.x)
                    if r <= app.shoreline(col)
                        app.h(r, col) = nan; % land
                    else
                        app.h(r, col) = -(2 * app.slope) * (r - app.shoreline(col)); % depth (m)
                    end
                end
            end

            
            % plot bathy
            ax = app.coastAxes;
            cla(ax);        
            set(ax, 'color', 'yellow');
            contourf(ax, app.x, app.y, app.h, 20);
            hold(ax, 'on');
            plot(ax, app.x, app.shoreline.*2, 'k-', 'linewidth', 2);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            % init user inputs
            app.breaking_wave_angle = app.waveAngleGauge.Value;
            app.breaking_wave_height = app.waveHeightSpinner.Value;
            app.pos = app.posSlider.Value;              
            
            % initialize global values
            app.x = (0:2:1000);
            app.y = (0:2:750);
            app.h = zeros(length(app.y), length(app.x));    
            
            % set up shoreline axes
            ax = app.coastAxes;
            colormap(ax, 'winter');
            ylim(ax, [0 app.y(end)]);
            xlim(ax, [0, app.x(end)]);
            ylabel(ax, 'cross-shore pos. (m)');   
            xlabel(ax, 'long-shore pos. (m)');
            axis(ax, 'equal');
            
            app.render_bathy();
            app.render_wave();
            app.render_plot();
            
            % initialize run settings
            app.stopped = 1;
            app.RunButton.Enable = 'on';
            app.StopButton.Enable = 'off';
        end

        % Value changed function: shorelineshapeDropDown
        function shorelineshapeDropDownValueChanged(app, event)
            app.render_bathy();
            app.render_wave();
            app.render_plot();
        end

        % Value changed function: waveHeightSpinner
        function waveHeightSpinnerValueChanged(app, event)
            app.breaking_wave_height = app.waveHeightSpinner.Value;
            app.render_plot();
        end

        % Button pushed function: wave_down
        function wave_downButtonPushed(app, event)
            app.wave_up.Enable = 'on';
            app.breaking_wave_angle = app.breaking_wave_angle - 5;
            app.waveAngleGauge.Value = app.breaking_wave_angle;
            if app.breaking_wave_angle == -90
                app.wave_down.Enable = 'off';
            end
            app.render_wave();
            app.render_plot();
        end

        % Button pushed function: wave_up
        function wave_upButtonPushed(app, event)
            app.wave_down.Enable = 'on';
            app.breaking_wave_angle = app.breaking_wave_angle + 5;
            app.waveAngleGauge.Value = app.breaking_wave_angle;
            if app.breaking_wave_angle == 90
                app.wave_up.Enable = 'off';
            end
            app.render_wave();
            app.render_plot();
        end

        % Value changed function: posSlider
        function posSliderValueChanged(app, event)
            app.pos = app.posSlider.Value;
            app.render_plot();            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.StopButton = uibutton(app.ControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RunButton = uibutton(app.ControlsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SetConditionsPanel = uipanel(app.UIFigure);
            app.SetConditionsPanel.Title = 'Set Conditions';
            app.shorelineshapeDropDownLabel = uilabel(app.SetConditionsPanel);
            app.shorelineshapeDropDownLabel.Text = 'shoreline shape';
            app.shorelineshapeDropDown = uidropdown(app.SetConditionsPanel);
            app.shorelineshapeDropDown.Items = {'linear', 'headland', 'bay'};
            app.shorelineshapeDropDown.ValueChangedFcn = createCallbackFcn(app, @shorelineshapeDropDownValueChanged, true);
            app.shorelineshapeDropDown.Value = 'headland';
            app.breakingwaveheightmLabel = uilabel(app.SetConditionsPanel);
            app.breakingwaveheightmLabel.Text = 'breaking wave height (m)';
            app.waveHeightSpinner = uispinner(app.SetConditionsPanel);
            app.waveHeightSpinner.ValueChangedFcn = createCallbackFcn(app, @waveHeightSpinnerValueChanged, true);
            app.waveHeightSpinner.Limits = [0 10];
            app.waveHeightSpinner.Value = 2;
            app.WaveInducedSedimentTransportLabel = uilabel(app.UIFigure);
            app.WaveInducedSedimentTransportLabel.Text = 'Wave-Induced Sediment Transport';
            app.coastAxes = uiaxes(app.UIFigure);
            xlabel(app.coastAxes, 'long-shore pos. (m)')
            ylabel(app.coastAxes, 'cross-shore pos. (m)')
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.Icon = 'help_icon.png';
            app.HelpButton.Text = 'Help';
            app.breakingwaveangledegLabel = uilabel(app.UIFigure);
            app.breakingwaveangledegLabel.Text = 'breaking wave angle (deg)';
            app.waveAngleGauge = uigauge(app.UIFigure, 'semicircular');
            app.waveAngleGauge.Limits = [-90 90];
            app.waveAngleGauge.ScaleDirection = 'counterclockwise';
            app.waveAngleGauge.Value = 45;
            app.wave_down = uibutton(app.UIFigure, 'push');
            app.wave_down.ButtonPushedFcn = createCallbackFcn(app, @wave_downButtonPushed, true);
            app.wave_down.Text = '-';
            app.wave_up = uibutton(app.UIFigure, 'push');
            app.wave_up.ButtonPushedFcn = createCallbackFcn(app, @wave_upButtonPushed, true);
            app.wave_up.Text = '+';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.posSlider = uislider(app.UIFigure);
            app.posSlider.Limits = [0 1000];
            app.posSlider.MajorTicks = [];
            app.posSlider.MajorTickLabels = {};
            app.posSlider.ValueChangedFcn = createCallbackFcn(app, @posSliderValueChanged, true);
            app.posSlider.MinorTicks = [];
            app.posSlider.Value = 500;
            app.fluxAxes = uiaxes(app.UIFigure);
            xlabel(app.fluxAxes, 'Sed. Flux (m^3)')
        end
    end

    methods (Access = public)

        % Construct app
        function app = SedTrans

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end